classdef SnakeGame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        StopButton               matlab.ui.control.Button
        Image2                   matlab.ui.control.Image
        Image                    matlab.ui.control.Image
        GameLabel                matlab.ui.control.Label
        DarkModeButton           matlab.ui.control.Button
        PauseButton              matlab.ui.control.Button
        DifficultySlider         matlab.ui.control.Slider
        DifficultySlider_2Label  matlab.ui.control.Label
        ScoreEditField           matlab.ui.control.NumericEditField
        ScoreLabel               matlab.ui.control.Label
        SnakeLabel               matlab.ui.control.Label
        StartButton              matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dir = 0;
        score = 0;
        gameSpeed = 0.7; %default game speed
        isPaused = false; % pause state
        snakeHandle; % handle for the snake plot
        foodHandle;  % handle for the food plot
        isDarkMode = false; %default light mode
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            dim = 20; %matrix dimention
            app.score = 0; %score
            app.dir = 2; %default direction when the game starts to the right
            app.isPaused = false; % ensure game starts unpaused
            app.PauseButton.Text = 'Pause'; % update button text
            cla(app.UIAxes)
            
            s = zeros(100,2);
            s(1,1) = round(dim/2); %find the middle of the screen on the x coordinate
            s(1,2) = round(dim/2); %find the middle of the screen on the y coordinate
            s(2,1) = 5;
            s(2,2) = 6;

            v = app.gameSpeed;
            f = [0 0]; %food appearance 
            f(1) = randi([1, dim]); % Random x position
            f(2) = randi([1, dim]); % Random y position

            % Ensure food does not overlap with the snake's initial position 
            while any(ismember(s(1:length(nonzeros(s(:, 1))), :), f, 'rows')) || isequal(f, s(1, :)) %condition for snake and food overlapping
                f(1) = randi([1, dim]); % regenerate x position
                f(2) = randi([1, dim]); % regenerate y position
            end

             % Set colors based on dark mode and light mode
            if app.isDarkMode
                snakeColor = [1, 0, 1]; % magenta snake
                foodColor = [1, 0, 0];  % red food
                gridColor = [0, 0, 0];  % black grid
            else
                snakeColor = [0, 1, 0]; % green snake
                foodColor = [1, 0, 0];  % red food
                gridColor = [1, 1, 1];  % white grid
            end

    % Apply grid background color
    set(app.UIAxes, 'Color', gridColor);
            
            %Plot snake
            app.snakeHandle = plot(app.UIAxes, s([1 2], 1), s([1 2], 2), 'Marker', ".", 'LineWidth', 10, 'Color', snakeColor);
            hold(app.UIAxes, "on");
            %Plot food
            app.foodHandle = plot(app.UIAxes, f(1, 1), f(1, 2), 'Marker', ".", 'MarkerSize', 30, 'MarkerFaceColor', foodColor);

            xlim(app.UIAxes, [0.5 dim + 0.5])
            ylim(app.UIAxes, [0.5 dim + 0.5])

            while(1)
                
                % Check if the game is paused
                while app.isPaused
                    pause(0.1); 
                end

                s_old = s;

                %Update head position
                if(app.dir == 0) %the snake moves left
                    s(1,1) = s_old(1, 1) - 1;
                    s(1,2) = s_old(1, 2);
                elseif(app.dir == 1) %the snake moves up
                    s(1,1) = s_old(1, 1);
                    s(1,2) = s_old(1, 2) + 1;
                elseif(app.dir == 2) %the snake moves right
                    s(1,1) = s_old(1, 1) + 1;
                    s(1,2) = s_old(1, 2);
                 elseif(app.dir == 3) %the snake moves down
                    s(1,1) = s_old(1, 1);
                    s(1,2) = s_old(1, 2) - 1;
                 end

                %Update body position
                for i = 2:1:length(nonzeros(s(:,1)))
                    s(i,1) = s_old(i-1, 1);
                    s(i,2) = s_old(i-1, 2);
                end

                %Wall collisions
                if(s(1,1)>dim||s(1,1)<1||s(1,2)>dim||s(1,2)<1)
                    msgbox(strcat('Wall collision. Game over! Score: ', int2str(app.score)), 'End') %showing a message
                    delete(app.snakeHandle);
                    delete(app.foodHandle);
                    break
                end

                 %Self collisions
                if(isempty(find(prod(s(1,1:2)==s(2:end,1:2), 2), 1))==0)
                    msgbox(strcat('Self collision. Game over! Score: ', int2str(app.score)), 'End') %showing a message
                    delete(app.snakeHandle);
                    delete(app.foodHandle);
                    break
                end

                %Eating
                if(s(1,[1 2])==f)
                    s(length(nonzeros(s(:,1)))+1,1) = s_old(length(nonzeros(s_old(:,1))),1); %moving the snake forward 
                    s(length(nonzeros(s(:,2)))+1,2) = s_old(length(nonzeros(s_old(:,2))),2);
                    
                    % Food generation logic
                    f(1) = randi([1, dim]); % random x position
                    f(2) = randi([1, dim]); % random y position
                    
                    % Ensure food does not overlap with the snake's position 
                    while any(ismember(s(1:length(nonzeros(s(:, 1))), :), f, 'rows')) || isequal(f, s(1, :)) %condition for snake and food overlapping
                        f(1) = randi([1, dim]); % regenerate x position
                        f(2) = randi([1, dim]); % regenerate y position
                    end

                    app.score = app.score+1; %rising the score
                    v = max(v-0.02, 0.1); %ensures the v does now go lower than 0.1
                end

                %Plotting
                app.ScoreEditField.Value = app.score; %displaying the score

                if isvalid(app.snakeHandle)
                    set(app.snakeHandle, 'XData', nonzeros(s(:,1)), 'YData', nonzeros(s(:,2))); %plotting the snake
                end

                if isvalid(app.foodHandle)
                    set(app.foodHandle, 'XData', nonzeros(f(:,1)), 'YData', nonzeros(f(:,2))); %plotting the food
                end
                pause(v)

            end
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            newDir = app.dir; % Store the current direction
            if(strcmp(key, 'leftarrow') && app.dir~=2) %left arrow pressed
                newDir = 0;
            elseif(strcmp(key, 'rightarrow') && app.dir~=0) %right arrow pressed
                newDir = 2;
            elseif(strcmp(key, 'uparrow') && app.dir~=3) %up arrow pressed
                newDir = 1;
            elseif(strcmp(key, 'downarrow') && app.dir~=1) %down arrow pressed
                newDir = 3;
            end

            % Only update the direction if it's valid
            if newDir ~= app.dir
                app.dir = newDir; % update the direction
                pause(0.1); % small pause to debounce key presses
            end
            
            
        end

        % Value changed function: DifficultySlider
        function DifficultySliderValueChanged(app, event)
            difficulty = app.DifficultySlider.Value;
            app.gameSpeed = (8-difficulty)/10; %formula that ensures that the speed will be between 0.7 and 0.2
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
             app.isPaused = ~app.isPaused; % invert pause state
            if app.isPaused
                app.PauseButton.Text = 'Resume'; % update button text for when the game is paused
            else
                app.PauseButton.Text = 'Pause'; % update button text for when the game is not paused
            end
        end

        % Button pushed function: DarkModeButton
        function DarkModeButtonPushed(app, event)
            app.isDarkMode = ~app.isDarkMode; % Toggle dark mode

            if app.isDarkMode
                % Set colors for dark mode
                snakeColor = [1, 0, 1]; % magenta snake
                foodColor = [1, 0, 0]; % red food
                gridColor = [0, 0, 0]; % black grid
                app.DarkModeButton.Text = 'Light Mode'; % update button text for when in dark mode
            else
                % Set colors for normal mode 
                snakeColor = [0, 1, 0]; % green snake
                foodColor = [1, 0, 0]; % red food
                gridColor = [1, 1, 1]; % white grid
                app.DarkModeButton.Text = 'Dark Mode'; % update button text for when in light mode
            end
        
            % Update the grid color
            set(app.UIAxes, 'Color', gridColor);

            % Update the snake color
            if isgraphics(app.snakeHandle)
                set(app.snakeHandle, 'Color', snakeColor);
            end
        
            % Update the food color
            if isgraphics(app.foodHandle)
                set(app.foodHandle, 'MarkerFaceColor', foodColor);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.isPaused = true; % pause the game
            msgbox('Game has been stopped.', 'End'); % show a message box
            delete(app.snakeHandle); % delete the snake
            delete(app.foodHandle); % delete the food
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [-1 5];
            app.UIAxes.YLim = [-1 5];
            app.UIAxes.XLimitMethod = 'padded';
            app.UIAxes.YLimitMethod = 'padded';
            app.UIAxes.ZLimitMethod = 'padded';
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SnakeLabel = uilabel(app.UIFigure);
            app.SnakeLabel.Text = 'Snake';
            app.ScoreLabel = uilabel(app.UIFigure);
            app.ScoreLabel.Text = 'Score:';
            app.ScoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.DifficultySlider_2Label = uilabel(app.UIFigure);
            app.DifficultySlider_2Label.Text = 'Difficulty';
            app.DifficultySlider = uislider(app.UIFigure);
            app.DifficultySlider.Limits = [1 6];
            app.DifficultySlider.ValueChangedFcn = createCallbackFcn(app, @DifficultySliderValueChanged, true);
            app.DifficultySlider.Tag = 'DifficultySlider';
            app.DifficultySlider.Value = 1;
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.DarkModeButton = uibutton(app.UIFigure, 'push');
            app.DarkModeButton.ButtonPushedFcn = createCallbackFcn(app, @DarkModeButtonPushed, true);
            app.DarkModeButton.Text = 'Dark Mode';
            app.GameLabel = uilabel(app.UIFigure);
            app.GameLabel.Text = 'Game';
            app.Image = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Snake.png');
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SnakeGame

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end